classdef InputConv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        menu                  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        PanelDataRate         matlab.ui.container.Panel
        Encode                matlab.ui.control.Button
        DataInputLabel        matlab.ui.control.Label
        MasukkansinyalinformasiberbentukbinerLabel  matlab.ui.control.Label
        Input                 matlab.ui.control.Button
        Biner0EditFieldLabel  matlab.ui.control.Label
        Biner0                matlab.ui.control.NumericEditField
        Biner1EditFieldLabel  matlab.ui.control.Label
        Biner1                matlab.ui.control.NumericEditField
        Biner2EditFieldLabel  matlab.ui.control.Label
        Biner2                matlab.ui.control.NumericEditField
        Biner3EditFieldLabel  matlab.ui.control.Label
        Biner3                matlab.ui.control.NumericEditField
        KlikencodeuntukmemprosesconvolutionalcodeLabel  matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        MainMenuButton        matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
        NextButton            matlab.ui.control.Button
        Plot                  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    methods (Access = private)
       
            function[hasil1,hasil2]=Generator12(isiRegister1,isiRegister2,isiRegister3)
                global m value3 value2 value1 value0;
                global r1 r2 r3 u1 u2 h1 h2 hasil1 hasil2;
                r1=isiRegister1;
                r2=isiRegister2;
                r3=isiRegister3;
                
                u1=r1+r3;       %Persamaan Output1
                u2=r1+r2+r3;    %Persamaan Output2
                
                h1=mod(u1,2);
                h2=mod(u2,2);
                
                hasil1=h1;      %Output1
                hasil2=h2;      %Output2
            end

                function[hasil,jumlah]=Encoder12(m)
                    global m value3 value2 value1 value0;
                    global mp R1 R2 R3 h i count ha jumlah hasil hasilpenyandian;
                    mp=Paritas(m);
                    R1=zeros;
                    R2=zeros;
                    R3=zeros;
                    h=zeros;
                    
                    for i=1:size(mp,2)
                        R3=R2;
                        R2=R1;
                        R1=mp(1,(size(mp,2)-i)+1);
                        [h1,h2]=Generator12(R1,R2,R3);
                        h(i,1)=h1;
                        h(i,2)=h2;
                    end
                    
                    ha=zeros;
                    count=1;
                    for i=1:size(h,1)
                        ha(1,count)=h(i,1);
                        count=count+1;
                        ha(1,count)=h(i,2);
                        count=count+1;
                    end
                    
                    jumlah=size(ha,2);
                    hasil=ha(1,1:end-4);
                    hasilpenyandian=hasil;
                    
                    disp hasil;
                    
                    savefile = 'hasilpenyandian12.mat';
                    save(savefile, 'hasilpenyandian');
                end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             % Load the data.
%             %load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
%             
%             % Store the data in a table and display it in one of the App's tabs.
%             app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
%             app.UITable.Data = app.Data;
%             app.BinWidth = app.BinWidthSlider.Value;
%             
%             % Update the axes with the corresponding data.
%             updateSelectedGenders(app)
%             refreshplot(app)


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.menu.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {338, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.BloodPressureSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
                
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Callback function
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            Genders = [];
            Colors = [];
            Smoker = [];
            
            if app.MaleCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.FemaleCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            if app.YesCheckBox.Value
                Smoker = "Yes";
            end
            if app.NoCheckBox.Value
                Smoker = [Smoker "No"];
            end
            
            if isempty(Genders) || isempty(Smoker)
                % Disable the switches and buttons if they were on
                app.BloodPressureSwitch.Enable = 'off';
                app.ScatterButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.BloodPressureSwitch.Enable = 'on';
                app.ScatterButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            
            refreshplot(app)
        end

        % Value changed function: Biner3
        function Biner3ValueChanged(app, event)
value3 = app.Biner3.Value;
        end

        % Value changed function: Biner2
        function Biner2ValueChanged(app, event)
value2 = app.Biner2.Value;
        end

        % Value changed function: Biner1
        function Biner1ValueChanged(app, event)
value1 = app.Biner1.Value;
        end

        % Value changed function: Biner0
        function Biner0ValueChanged(app, event)
value0 = app.Biner0.Value;
        end

        % Button pushed function: Input
        function InputButtonPushed(app, event)
        global m value3 value2 value1 value0;
        value3 = app.Biner3.Value;
        value2 = app.Biner2.Value;  
        value1 = app.Biner1.Value;
        value0 = app.Biner0.Value;
        m = [value3 value2 value1 value0];
       
        end

        % Callback function
        function DataRateDropDownOpening(app, event)
            
        end

        % Button pushed function: Encode
        function EncodeButtonPushed(app, event)
        global r1 r2 r3 u1 u2 h1 h2 hasil1 hasil2;
        global mp R1 R2 R3 h i count ha jumlah hasil hasilpenyandian;
       [hasil1,hasil2]=Generator12(isiRegister1,isiRegister2,isiRegister3);
       [hasil,jumlah]=Encoder12(m);
        end

        % Callback function
        function sepertigaButtonPushed(app, event)
            
        end

        % Callback function
        function seperempatButtonPushed(app, event)
            
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            run mainmenu;
            closereq();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            run ViterbiPlot;
            closereq();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.menu = uifigure('Visible', 'off');
            app.menu.Name = 'Patients Display';
            app.menu.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.menu);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PanelDataRate = uipanel(app.LeftPanel);
            app.PanelDataRate.Title = 'Klik untuk memproses sinyal';
            app.Encode = uibutton(app.PanelDataRate, 'push');
            app.Encode.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.Encode.Text = 'Encode';
            app.DataInputLabel = uilabel(app.LeftPanel);
            app.DataInputLabel.Text = 'Data Input';
            app.MasukkansinyalinformasiberbentukbinerLabel = uilabel(app.LeftPanel);
            app.MasukkansinyalinformasiberbentukbinerLabel.Text = '*Masukkan sinyal informasi berbentuk biner*';
            app.Input = uibutton(app.LeftPanel, 'push');
            app.Input.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.Input.Text = 'Input';
            app.Biner0EditFieldLabel = uilabel(app.LeftPanel);
            app.Biner0EditFieldLabel.Text = 'Biner 0';
            app.Biner0 = uieditfield(app.LeftPanel, 'numeric');
            app.Biner0.ValueChangedFcn = createCallbackFcn(app, @Biner0ValueChanged, true);
            app.Biner0.Value = 1;
            app.Biner1EditFieldLabel = uilabel(app.LeftPanel);
            app.Biner1EditFieldLabel.Text = 'Biner 1';
            app.Biner1 = uieditfield(app.LeftPanel, 'numeric');
            app.Biner1.ValueChangedFcn = createCallbackFcn(app, @Biner1ValueChanged, true);
            app.Biner1.Value = 1;
            app.Biner2EditFieldLabel = uilabel(app.LeftPanel);
            app.Biner2EditFieldLabel.Text = 'Biner 2';
            app.Biner2 = uieditfield(app.LeftPanel, 'numeric');
            app.Biner2.ValueChangedFcn = createCallbackFcn(app, @Biner2ValueChanged, true);
            app.Biner3EditFieldLabel = uilabel(app.LeftPanel);
            app.Biner3EditFieldLabel.Text = 'Biner 3';
            app.Biner3 = uieditfield(app.LeftPanel, 'numeric');
            app.Biner3.ValueChangedFcn = createCallbackFcn(app, @Biner3ValueChanged, true);
            app.Biner3.Value = 1;
            app.KlikencodeuntukmemprosesconvolutionalcodeLabel = uilabel(app.LeftPanel);
            app.KlikencodeuntukmemprosesconvolutionalcodeLabel.Text = '*Klik Clear untuk mereset data rate*';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainMenuButton = uibutton(app.RightPanel, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ExitButton = uibutton(app.RightPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Plot = uibutton(app.RightPanel, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Convolutional Encoding')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.menu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputConv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.menu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.menu)
        end
    end
end