classdef simulasi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ConvolutionalEncodingLabel  matlab.ui.control.Label
        PlotBERButton               matlab.ui.control.Button
        MainMenuButton              matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        MasukanangkadalambentukbinerLabel  matlab.ui.control.Label
        Biner3EditFieldLabel        matlab.ui.control.Label
        Biner3EditField             matlab.ui.control.NumericEditField
        Biner2EditFieldLabel        matlab.ui.control.Label
        Biner2EditField             matlab.ui.control.NumericEditField
        Biner1EditFieldLabel        matlab.ui.control.Label
        Biner1EditField             matlab.ui.control.NumericEditField
        Biner0EditFieldLabel        matlab.ui.control.Label
        Biner0EditField             matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
                
        function[hasil,jumlah]=Encoder12(m)
            
                mp=m;
                    R1=zeros;
                    R2=zeros;
                    h=zeros;
                    
                    for i=1:numel(mp)
                        R3=R2;
                        R2=R1;
                        R1=mp(i);
                        [h1,h2]=Generator12(R1,R2,R3);
                        h(i,1)=h1;
                        h(i,2)=h2;
                    end
                                      
                    jumlah=numel(mp);
                    disp(h)
                    hasil=reshape(h,1,[]) ;             
                    disp hasil;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Biner0EditField
        function Biner0EditFieldValueChanged(app, event)
            value0 = app.Biner0EditField.Value;
            
        end

        % Button pushed function: PlotBERButton
        function PlotBERButtonPushed(app, event)
        value3=app.Biner3EditField.Value;
        value2=app.Biner2EditField.Value;
        value1=app.Biner1EditField.Value;
        value0=app.Biner0EditField.Value;
       
        m = [value0 value1 value2 value3]; 
            
        [hasil,jumlah]=Encoder12(m);
            
        M = 16;
        k = log2(M);    
            
        trellis = poly2trellis(3,[hasil jumlah]);
        traceBack = 16;
        codeRate = 1/2;
        
        convEncoder = comm.ConvolutionalEncoder('TrellisStructure',trellis);
        vitDecoder = comm.ViterbiDecoder('TrellisStructure',trellis, ...
        'InputFormat','Hard','TracebackDepth',traceBack);
        
        errorRate = comm.ErrorRate('ReceiveDelay',2*traceBack);
        
        ebnoVec = 0:2:10;
        errorStats = zeros(length(ebnoVec),3);
        
        for m = 1:length(ebnoVec)
            snr = ebnoVec(m) + 10*log10(k*codeRate);
            while errorStats(m,2) <= 100 && errorStats(m,3) <= 1e7
                dataIn = randi([0 1],10000,1);
                dataEnc = convEncoder(dataIn);
                txSig = qammod(dataEnc,M, ...
                    'InputType','bit','UnitAveragePower',true);
                rxSig = awgn(txSig,snr);
                demodSig = qamdemod(rxSig,M, ...
                    'OutputType','bit','UnitAveragePower',true);
                dataOut = vitDecoder(demodSig);
                errorStats(m,:) = errorRate(dataIn,dataOut);
            end
        reset(errorRate)
        end
        
        berUncoded = berawgn(ebnoVec','qam',M);
%         semilogy(ebnoVec,[errorStats(:,1) berUncoded])
%         grid
%         legend('Coded','Uncoded')
%         xlabel('Eb/No (dB)')
%         ylabel('Bit Error Rate')
        ax = app.UIAxes;
        ax.YScale = 'log';
        ax.YGrid = 'on';
        plot(app.UIAxes,ebnoVec,[errorStats(:,1) berUncoded]);        
        
        end

        % Value changed function: Biner3EditField
        function Biner3EditFieldValueChanged(app, event)
            value = app.Biner3EditField.Value;
            
        end

        % Value changed function: Biner2EditField
        function Biner2EditFieldValueChanged(app, event)
            value = app.Biner2EditField.Value;
            
        end

        % Value changed function: Biner1EditField
        function Biner1EditFieldValueChanged(app, event)
            value = app.Biner1EditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            run mainmenu;
            closereq ();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvolutionalEncodingLabel = uilabel(app.UIFigure);
            app.ConvolutionalEncodingLabel.Text = 'Convolutional Encoding';
            app.PlotBERButton = uibutton(app.UIFigure, 'push');
            app.PlotBERButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBERButtonPushed, true);
            app.PlotBERButton.Text = 'Plot BER';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Panel = uipanel(app.UIFigure);
            app.MasukanangkadalambentukbinerLabel = uilabel(app.Panel);
            app.MasukanangkadalambentukbinerLabel.Text = 'Masukan angka dalam bentuk biner';
            app.Biner3EditFieldLabel = uilabel(app.Panel);
            app.Biner3EditFieldLabel.Text = 'Biner 3';
            app.Biner3EditField = uieditfield(app.Panel, 'numeric');
            app.Biner3EditField.ValueChangedFcn = createCallbackFcn(app, @Biner3EditFieldValueChanged, true);
            app.Biner2EditFieldLabel = uilabel(app.Panel);
            app.Biner2EditFieldLabel.Text = 'Biner 2';
            app.Biner2EditField = uieditfield(app.Panel, 'numeric');
            app.Biner2EditField.ValueChangedFcn = createCallbackFcn(app, @Biner2EditFieldValueChanged, true);
            app.Biner1EditFieldLabel = uilabel(app.Panel);
            app.Biner1EditFieldLabel.Text = 'Biner 1';
            app.Biner1EditField = uieditfield(app.Panel, 'numeric');
            app.Biner1EditField.ValueChangedFcn = createCallbackFcn(app, @Biner1EditFieldValueChanged, true);
            app.Biner0EditFieldLabel = uilabel(app.Panel);
            app.Biner0EditFieldLabel.Text = 'Biner 0';
            app.Biner0EditField = uieditfield(app.Panel, 'numeric');
            app.Biner0EditField.ValueChangedFcn = createCallbackFcn(app, @Biner0EditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot BER vs Eb/No (dB)')
            xlabel(app.UIAxes, 'Bit Error rate')
            ylabel(app.UIAxes, 'Eb/No (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulasi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end